classdef rsa_simple_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        Label                    matlab.ui.control.Label
        RunAnalysisButton        matlab.ui.control.Button
        PointmetoyourstudydirectoryButton  matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        StatusLabel              matlab.ui.control.Label
        StudyPathEditField       matlab.ui.control.EditField
        StudyPathEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PointmetoyourstudydirectoryButton
        function PointmetoyourstudydirectoryButtonPushed(app, event)
            % Open the folder selection dialog
            selectedFolder = uigetdir();

            % uigetdir returns 0 if the user clicks "Cancel"
            if selectedFolder ~= 0
                % Update the text box with the chosen path
                app.StudyPathEditField.Value = selectedFolder;
            end
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            % Grab the folder path from the UI
            studytargetFolder = app.StudyPathEditField.Value;

            % Safety check: Ensure the user actually selected a valid folder
            if isempty(studytargetFolder) || ~isfolder(studytargetFolder)
                uialert(app.UIFigure, 'Please browse and select a valid folder first.', 'Invalid Path');
                return; % Stop execution here
            end

            % Update UI to show it is working
            app.StatusLabel.Text = 'Processing data...';
            drawnow; % Forces the UI to update immediately

            % Pass the path into your external complex function
            try
                rsa_simple(studytargetFolder);
                app.StatusLabel.Text = 'Analysis Complete!';
            catch ME
                uialert(app.UIFigure, ['Error during execution: ', ME.message], 'Function Error');
                app.StatusLabel.Text = 'Error occurred.';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PointmetoyourstudydirectoryButton = uibutton(app.LeftPanel, 'push');
            app.PointmetoyourstudydirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PointmetoyourstudydirectoryButtonPushed, true);
            app.PointmetoyourstudydirectoryButton.Text = 'Point me to your study directory';
            app.RunAnalysisButton = uibutton(app.LeftPanel, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'This assumes your datasets (e.g., each subjects'' MRI data) are subdirectories of this study folder';
            app.RightPanel = uipanel(app.GridLayout);
            app.StudyPathEditFieldLabel = uilabel(app.RightPanel);
            app.StudyPathEditFieldLabel.Text = 'Study Path';
            app.StudyPathEditField = uieditfield(app.RightPanel, 'text');
            app.StatusLabel = uilabel(app.RightPanel);
            app.StatusLabel.Text = 'Status:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rsa_simple_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end